classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SerialPortSelectorDropDown  matlab.ui.control.DropDown
        SerialPortSelectorDropDownLabel  matlab.ui.control.Label
        UpdateButton                matlab.ui.control.Button
        BreakEditField              matlab.ui.control.NumericEditField
        BreakEditFieldLabel         matlab.ui.control.Label
        RPMBaisEditField            matlab.ui.control.NumericEditField
        RPMBaisEditFieldLabel       matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        ThrottleEditField           matlab.ui.control.NumericEditField
        ThrottleEditFieldLabel      matlab.ui.control.Label
        RunTimeEditField            matlab.ui.control.NumericEditField
        RunTimeEditFieldLabel       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        newData % last read serial line
        runData % all data from current run
        serialObj % the teensy
        serialPort % port the teensy is connected to
        readCount % counts number of read actions
        writeCount % counts number of write actions
        filename % name of current run
        nameFormat = string() % file printing format for col names
        dataFormat = string() % file printing format for colected data
        numSensors % number of sensors outputing data
        sensorNames % names of all sensors
        started = 0 % Catches hiting stop before start
        writeTimer % inturupt timer for writing to the serial
        runTimer % inturupt timer for run time
        runTime = 0.001 % lenght of run time
        throttleVal = 0 % throttle precentage 
        breakVal = 0 % break precentage 
        rpmGraphingBais % The value the graph centers the rmp @
    end
    
    methods (Access = private)
        
        function serialInit(app)
            clc;
            app.started = 1;
            app.filename = string(datetime('now','TimeZone','local','Format','dd-MM-yyyy @HHmm'))+".csv";
            app.readCount = 0;
            app.writeCount = 0;
            app.serialObj = serialport(app.serialPort, 115200);
            configureTerminator(app.serialObj,"CR/LF");
            configureCallback(app.serialObj,"terminator",@app.serialRead);
            %app.writeTimer = timer("Period",1,"TimerFcn","app.serialWrite()","ExecutionMode","fixedSpacing");
            %app.runTimer = timer("Period",app.runTime,"TimerFcn","app.kill()","ExecutionMode","fixedSpacing");
            %start(app.writeTimer);
        end
        
        function serialRead(app,~,~)
            % Read the ASCII data from the serialport object.
            try
            new = readline(app.serialObj);
            catch
                flush(app.serialObj,"input");
                return;
            end
            app.newData = split(new,',');
            if(app.newData(1) == "BA" && app.newData(end) == "JA")
                app.numSensors = int8(char(app.newData(3))-48); % converts string to uint8 max 9 sensors
                if(app.newData(2) == "ND")
                    for i = 1:app.numSensors
                        app.sensorNames(i) = app.newData(4+i);
                    end
                end
                if(app.newData(2) == "SD")
                    app.readCount = app.readCount + 1;
                    app.runData(app.readCount,1) = app.newData(4);
                    for i = 1:app.numSensors
                        app.runData(app.readCount,i+1) = app.newData(i+4);
                    end
                end
            end
            flush(app.serialObj,"input");
            index = 1 + find(app.sensorNames=="oprpm"); % might need to change based on acl sensor name
            plot(app.UIAxes,app.runData(:,index)-app.rpmGraphingBais);
        end
        
        function kill(app)
            if(app.started)
                configureCallback(app.serialObj, "off");
                %stop(app.writeTimer);
                %stop(app.runTimer);
                %delete(app.writeTimer);
                %delete(app.runTimer);
                for i = 1:app.numSensors-1
                    app.nameFormat = app.nameFormat + "%s,";
                    app.dataFormat = app.dataFormat + "%d,";
                end 
                app.nameFormat = app.nameFormat + "%s\n";
                app.dataFormat = app.dataFormat + "%d\n";
                fileID = fopen(app.filename,'w');
                fprintf(fileID,'%s\n',datetime('now','TimeZone','local','Format','dd/MM/yyy HH:mm'));
                fprintf(fileID,app.nameFormat,app.sensorNames);
                fprintf(fileID,app.dataFormat,app.runData);
                fclose(fileID);
                %writematrix(app.runData,filename);
                %t = array2table(app.runData,'VariableNames',app.sensorNames);
                %writetable(t,app.filename);
                writeline(app.serialObj, "BA,TC,0,BC,0,JA");
                flush(app.serialObj);
                app.started = 0;
            end
        end
        
        function serialWrite(app)
            str = "BA,TC," + app.throttleVal + ",BC," + app.breakVal + ",JA";
            disp(str);
            writeline(app.serialObj, str);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.serialInit();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.kill();
        end

        % Value changed function: RunTimeEditField
        function RunTimeEditFieldValueChanged(app, event)
            value = app.RunTimeEditField.Value;
            app.runTime = value; 
        end

        % Value changed function: ThrottleEditField
        function ThrottleEditFieldValueChanged(app, event)
            value = app.ThrottleEditField.Value;
            app.throttleVal = value;
        end

        % Value changed function: BreakEditField
        function BreakEditFieldValueChanged(app, event)
            value = app.BreakEditField.Value;
            app.breakVal = value; 
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.serialWrite();
        end

        % Drop down opening function: SerialPortSelectorDropDown
        function SerialPortSelectorDropDownOpening(app, event)
            app.SerialPortSelectorDropDown.Items = serialportlist;
            app.SerialPortSelectorDropDown.ItemsData = serialportlist;
        end

        % Value changed function: SerialPortSelectorDropDown
        function SerialPortSelectorDropDownValueChanged(app, event)
            value = app.SerialPortSelectorDropDown.Value;
            app.serialPort = value;
        end

        % Value changed function: RPMBaisEditField
        function RPMBaisEditFieldValueChanged(app, event)
            value = app.RPMBaisEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RunTimeEditFieldLabel.Text = 'Run Time';
            app.RunTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RunTimeEditFieldValueChanged, true);
            app.ThrottleEditFieldLabel = uilabel(app.UIFigure);
            app.ThrottleEditFieldLabel.Text = 'Throttle';
            app.ThrottleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThrottleEditField.ValueChangedFcn = createCallbackFcn(app, @ThrottleEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RPMBaisEditFieldLabel = uilabel(app.UIFigure);
            app.RPMBaisEditFieldLabel.Text = 'RPM Bais';
            app.RPMBaisEditField = uieditfield(app.UIFigure, 'numeric');
            app.RPMBaisEditField.ValueChangedFcn = createCallbackFcn(app, @RPMBaisEditFieldValueChanged, true);
            app.RPMBaisEditField.Value = 2500;
            app.BreakEditFieldLabel = uilabel(app.UIFigure);
            app.BreakEditFieldLabel.Text = 'Break';
            app.BreakEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakEditField.ValueChangedFcn = createCallbackFcn(app, @BreakEditFieldValueChanged, true);
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SerialPortSelectorDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortSelectorDropDownLabel.Text = 'Serial Port Selector';
            app.SerialPortSelectorDropDown = uidropdown(app.UIFigure);
            app.SerialPortSelectorDropDown.Items = {'COM3'};
            app.SerialPortSelectorDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortSelectorDropDownOpening, true);
            app.SerialPortSelectorDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialPortSelectorDropDownValueChanged, true);
            app.SerialPortSelectorDropDown.Value = 'COM3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end